classdef svm_bow_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        browseButton    matlab.ui.control.Button
        ImageLabel      matlab.ui.control.Label
        output_field    matlab.ui.control.EditField
        image           matlab.ui.control.UIAxes
        feature         matlab.ui.control.UIAxes
        classifyButton  matlab.ui.control.Button
    end


    properties (Access = private)
        Property % Description
        model = 0;
        bag = 0;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            model = load('E:\BTL_Y_sinh\BLT_XLAYT\svm_bow_classifier.mat');
            app.model = model.model;
            bag   = load('E:\BTL_Y_sinh\BLT_XLAYT\boVW.mat');
            app.bag   = bag.bag;
            app.image.Title.String = 'image';
            app.feature.Title.String = 'feature';
        end

        % Button pushed function: browseButton
        function browseButtonPushed(app, event)
            image_name = uigetimagefile();
            app.output_field.Value = image_name;
        end

        % Button pushed function: classifyButton
        function classifyButtonPushed(app, event)
            img = imread(app.output_field.Value);
            imshow(img, 'parent', app.image);
            featureVector = encode(app.bag, img);
            [Y_pred, ~] = predict(app.model, featureVector);
            if (Y_pred == 0)
                app.image.Title.String = 'Normal';
            else
                app.image.Title.String = 'Abnormal';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.browseButton = uibutton(app.UIFigure, 'push');
            app.browseButton.ButtonPushedFcn = createCallbackFcn(app, @browseButtonPushed, true);
            app.browseButton.Text = 'browse';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image';
            app.output_field = uieditfield(app.UIFigure, 'text');
            app.image = uiaxes(app.UIFigure);
            title(app.image, 'Title')
            xlabel(app.image, 'X')
            ylabel(app.image, 'Y')
            app.feature = uiaxes(app.UIFigure);
            title(app.feature, 'Title')
            xlabel(app.feature, 'X')
            ylabel(app.feature, 'Y')
            app.classifyButton = uibutton(app.UIFigure, 'push');
            app.classifyButton.ButtonPushedFcn = createCallbackFcn(app, @classifyButtonPushed, true);
            app.classifyButton.Text = 'classify';
        end
    end

    methods (Access = public)

        % Construct app
        function app = svm_bow_gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end